classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        ObservadordeestadosButtonGroup  matlab.ui.container.ButtonGroup
        SemObservadorButton             matlab.ui.control.RadioButton
        ComObservadorButton             matlab.ui.control.RadioButton
        LinearizaroButtonGroup          matlab.ui.container.ButtonGroup
        NoLinearButton                  matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        StopSimulationButton            matlab.ui.control.Button
        AmplitudeEntradaSlider          matlab.ui.control.Slider
        AmplitudeEntradaSliderLabel     matlab.ui.control.Label
        TempoEditField                  matlab.ui.control.NumericEditField
        TempoEditFieldLabel             matlab.ui.control.Label
        EntradaButtonGroup              matlab.ui.container.ButtonGroup
        SineButton                      matlab.ui.control.RadioButton
        DegrauButton                    matlab.ui.control.RadioButton
        ControladorButtonGroup          matlab.ui.container.ButtonGroup
        PIDButton                       matlab.ui.control.RadioButton
        MPCButton                       matlab.ui.control.RadioButton
        alocacaoButton                  matlab.ui.control.RadioButton
        LQRButton                       matlab.ui.control.RadioButton
        dEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        deltaEditField                  matlab.ui.control.NumericEditField
        deltaEditFieldLabel             matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        mvEditField                     matlab.ui.control.NumericEditField
        mvEditFieldLabel                matlab.ui.control.Label
        mbEditField                     matlab.ui.control.NumericEditField
        mbEditFieldLabel                matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        grafico_simulacao               matlab.ui.control.UIAxes
        grafico_acao_controle           matlab.ui.control.UIAxes
        Tab3                            matlab.ui.container.Tab
        grafico_estados1                matlab.ui.control.UIAxes
        grafico_estados2                matlab.ui.control.UIAxes
        grafico_estados3                matlab.ui.control.UIAxes
        grafico_estados4                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            syms  s;
            cla(app.grafico_simulacao)
            cla(app.grafico_acao_controle)
            cla(app.grafico_estados1)
            cla(app.grafico_estados2)
            cla(app.grafico_estados3)
            cla(app.grafico_estados4)
            
            g = 9.8;
            mb = app.mbEditField.Value;
            mv = app.mvEditField.Value;
            L  = app.LEditField.Value;
            d = app.dEditField.Value;
            R  = app.REditField.Value;
            delta = app.deltaEditField.Value;
            tempo_simulacao = app.TempoEditField.Value;
            amplitude_entrada = app.AmplitudeEntradaSlider.Value;
            bool_degrau = app.DegrauButton.Value;
            bool_linear = app.LinearButton.Value;

            mpc1 = load('mpc_session_designer_ball_and_beam_linear.mat')
            mpc1 = mpc1.MPCDesignerSession.AppData.Controllers.MPC 

            Jv =  (1/2) * mv * L^2;
      
            a23 = -(( mb * Jv *g + (mb^2 )*g * delta^2 )/((Jv + mb*delta^2)^2));
            a41 = -5*g/7;
            
            A = [ 0 1 0  0 ; 
                  0  0  a23 0; 
                    0 0 0 1; 
                  a41 0 0 0 ];
         

            B = [0 ;
                 1;
                 0 ;
                 0];
            
            C = [0 0 1 0];
            
            D = [0];
            sys = ss(A,B,C,D)
            [a,b] = ss2tf(A,B,C,D)
            F  = tf(a,b)
            F0 = a(5)/b(5) % funcao de transferencia com S avaliado em zero

            A_nulo = [A; 
                      C]
            coluna_nula = zeros(5,1)
            A_nulo = [A_nulo coluna_nula]
            
            B_nulo = [B; 
                 0]
            C_nulo = [C 1]
            r_nulo = [0;0;0;0;-1]
            z_nulo = C_nulo - r_nulo
            
            
            % default LQR
            Q_nulo = eye(5);
            R = 0.5;
            % equação de riccati
            P_nulo = icare(A_nulo,B_nulo,Q_nulo,R)
            K_nulo = (R^-1)*transpose(B_nulo)*P_nulo
            M_nulo = (F0^-1)*transpose(K_nulo)*(K_nulo*transpose(K_nulo))^-1
            k_seguimento_lqr = K_nulo(1:4)
            ki_seguimento_lqr = K_nulo(5)

            if app.alocacaoButton.Value
                pb = [-0.3 -2.5 -5 -10 -11]
                kb_seguimento_alocacao = acker(A_nulo, B_nulo, pb)
                k_seguimento_lqr = kb_seguimento_alocacao(1:4)
                ki_seguimento_lqr = kb_seguimento_alocacao(5)
            end 


            %% Observador de estados 
            polo_mais_rapido_do_sistema = min(real(eig(A)))
            
            polo_de_f = 1.5 * polo_mais_rapido_do_sistema
            sistema_f = (s - polo_de_f)^length(A)
            dem = sym2poly(sistema_f)
            
            tf_sistema = tf(1,dem)
            [num, dem] = tfdata(tf_sistema, 'v')
            [F,lixo1,lixo2,lixo3] = tf2ss(num,dem)
            
            
            Lchp = [0; 0; 0; 1]
            
            estados_incontrolaveis = length(A) - rank(ctrb(F,Lchp))
            
            
            T = lyap(F, -A,Lchp*C)
            L_observador = inv(T)*Lchp

            %% Passando pro simulink 
        
            modelo = 'lqr_nao_linear_simulink'
            load_system(modelo)
            set_param(modelo,'StartTime','0','StopTime',num2str(tempo_simulacao))
            in = Simulink.SimulationInput(modelo)
            in = in.setVariable('g',g)
            in = in.setVariable('mb',mb)
            in = in.setVariable('L',L)
            in = in.setVariable('mv',mv)
            in = in.setVariable('d',d)
            in = in.setVariable('R',R)
            in = in.setVariable('delta',delta)
            in = in.setVariable('Jv',Jv)
            in = in.setVariable('A',A)
            in = in.setVariable('B',B)
            in = in.setVariable('C',C)
            in = in.setVariable('D',D)
            in = in.setVariable('k_seguimento_lqr',k_seguimento_lqr)
            in = in.setVariable('ki_seguimento_lqr',ki_seguimento_lqr)
            in = in.setVariable('amplitude_entrada', amplitude_entrada)
            in = in.setVariable('L_observador', L_observador)
               
            in = in.setVariable('bool_degrau', bool_degrau)
            in = in.setVariable('bool_linear',bool_linear)
            
            in = in.setVariable('mpc1', mpc1)
            out = sim(in)

            %%  Plotando grafico MPC 

            if (app.LinearButton.Value == 1) && (app.ComObservadorButton.Value == 0) && (app.MPCButton.Value == 1)   
                Time = out.u_output_mpc_linear.Time; 
                y_out  = out.y_output_mpc_linear.Data;
                u_out = out.u_output_mpc_linear.Data;
                acao_controle = out.acao_controle_mpc_linear.Data;
                Time_acao_controle = out.acao_controle_mpc_linear.Time
                estados1 = out.estados_mpc_linear1.Data
                estados2 = out.estados_mpc_linear2.Data
                estados3 = out.estados_mpc_linear3.Data
                estados4 = out.estados_mpc_linear4.Data
                
                plot(app.grafico_simulacao, Time,y_out)
                hold(app.grafico_simulacao,'on');
                plot(app.grafico_simulacao,Time, u_out) 
                
                plot(app.grafico_acao_controle, Time_acao_controle, acao_controle)

                plot(app.grafico_estados1,Time,estados1)
                plot(app.grafico_estados2,Time,estados2)
                plot(app.grafico_estados3,Time,estados3)
                plot(app.grafico_estados4,Time,estados4)

                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')
                legend(app.grafico_estados1,'Posição Angular Viga')
                legend(app.grafico_estados2,'Velocidade Angular Viga')
                legend(app.grafico_estados3,'Posição Bola')
                legend(app.grafico_estados4,'Velocidade Bola')

            end
            %% plotando graficos
            if (app.NoLinearButton.Value == 1) && (app.ComObservadorButton.Value == 0) && (app.MPCButton.Value ~= 1)
                Time = out.u_output_nao_linear.Time;
                y_out  = out.y_output_nao_linear.Data;
                u_out = out.u_output_nao_linear.Data;
                acao_controle = out.acao_controle_nao_linear.Data

                estados1 = out.estados_nao_linear1.Data
                estados2 = out.estados_nao_linear2.Data
                estados3 = out.estados_nao_linear3.Data
                estados4 = out.estados_nao_linear4.Data

                plot(app.grafico_simulacao, Time,y_out)
                hold(app.grafico_simulacao,'on');
                plot(app.grafico_simulacao,Time, u_out)
                
                plot(app.grafico_acao_controle,Time,acao_controle)

                plot(app.grafico_estados1,Time,estados1)
                plot(app.grafico_estados2,Time,estados2)
                plot(app.grafico_estados3,Time,estados3)
                plot(app.grafico_estados4,Time,estados4)

                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')
                legend(app.grafico_estados1,'Posição Angular Viga')
                legend(app.grafico_estados2,'Velocidade Angular Viga')
                legend(app.grafico_estados3,'Posição Bola')
                legend(app.grafico_estados4,'Velocidade Bola')
             
            end 
            
            if (app.LinearButton.Value == 1) && (app.ComObservadorButton.Value == 0) && (app.MPCButton.Value ~= 1)
                Time = out.u_output_linear.Time;
                y_out  = out.y_output_linear.Data;
                u_out = out.u_output_linear.Data;
                acao_controle = out.acao_controle_linear.Data

                estados1 = out.estados_linear1.Data
                estados2 = out.estados_linear2.Data
                estados3 = out.estados_linear3.Data
                estados4 = out.estados_linear4.Data

                plot(app.grafico_simulacao, Time,y_out)
                hold(app.grafico_simulacao,'on');
                plot(app.grafico_simulacao,Time, u_out)
                plot(app.grafico_acao_controle,Time,acao_controle)
                
                plot(app.grafico_estados1,Time,estados1)
                plot(app.grafico_estados2,Time,estados2)
                plot(app.grafico_estados3,Time,estados3)
                plot(app.grafico_estados4,Time,estados4)
                
                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')

                legend(app.grafico_estados1,'Posição Angular Viga')
                legend(app.grafico_estados2,'Velocidade Angular Viga')
                legend(app.grafico_estados3,'Posição Bola')
                legend(app.grafico_estados4,'Velocidade Bola')
                

            end 

            if (app.NoLinearButton.Value == 1) && (app.ComObservadorButton.Value == 1) && (app.MPCButton.Value ~= 1)
                Time = out.u_output_nao_linear_observador.Time;
                y_out  = out.y_output_nao_linear_observador.Data;
                u_out = out.u_output_nao_linear_observador.Data;
                acao_controle = out.acao_controle_nao_linear_observador.Data

                estados1 = out.estados_nao_linear_observador1.Data
                estados2 = out.estados_nao_linear_observador2.Data
                estados3 = out.estados_nao_linear_observador3.Data
                estados4 = out.estados_nao_linear_observador4.Data
                estados_estimados1 = out.estados_nao_linear_estimados_observador1.Data
                estados_estimados2 = out.estados_nao_linear_estimados_observador2.Data
                estados_estimados3 = out.estados_nao_linear_estimados_observador3.Data
                estados_estimados4 = out.estados_nao_linear_estimados_observador4.Data


                plot(app.grafico_simulacao, Time,y_out)
                hold(app.grafico_simulacao,'on');
                plot(app.grafico_simulacao,Time, u_out)
                plot(app.grafico_acao_controle,Time,acao_controle)

                plot(app.grafico_estados1,Time,estados1)
                hold(app.grafico_estados1,'on')
                plot(app.grafico_estados1,Time,estados_estimados1)

                plot(app.grafico_estados2,Time,estados2)
                hold(app.grafico_estados2,'on')
                plot(app.grafico_estados2,Time,estados_estimados2)

                plot(app.grafico_estados3,Time,estados3)
                hold(app.grafico_estados3,'on')
                plot(app.grafico_estados3,Time,estados_estimados3)

                plot(app.grafico_estados4,Time,estados4)
                hold(app.grafico_estados4,'on')
                plot(app.grafico_estados4,Time,estados_estimados4)


                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')

                legend(app.grafico_estados1,'Posição Angular Viga','Posicao Estimada')
                legend(app.grafico_estados2,'Velocidade Angular Viga','Velocidade Estimada')
                legend(app.grafico_estados3,'Posição Bola', 'Posicao Estimada')
                legend(app.grafico_estados4,'Velocidade Bola','Velocidade Estimada')
            end 

            if (app.LinearButton.Value == 1) && (app.ComObservadorButton.Value == 1) && (app.MPCButton.Value ~= 1)
                Time = out.u_output_linear_observador.Time;
                y_out  = out.y_output_linear_observador.Data;
                u_out = out.u_output_linear_observador.Data;
                acao_controle = out.acao_controle_linear_observador.Data

                estados1 = out.estados_linear_observador1.Data
                estados2 = out.estados_linear_observador2.Data
                estados3 = out.estados_linear_observador3.Data
                estados4 = out.estados_linear_observador4.Data
                estados_estimados1 = out.estados_linear_estimados_observador1.Data
                estados_estimados2 = out.estados_linear_estimados_observador2.Data
                estados_estimados3 = out.estados_linear_estimados_observador3.Data
                estados_estimados4 = out.estados_linear_estimados_observador4.Data


                plot(app.grafico_simulacao, Time,y_out)
                hold(app.grafico_simulacao,'on');
                plot(app.grafico_simulacao,Time, u_out)
                plot(app.grafico_acao_controle,Time,acao_controle)

                plot(app.grafico_estados1,Time,estados1)
                hold(app.grafico_estados1,'on')
                plot(app.grafico_estados1,Time,estados_estimados1)

                plot(app.grafico_estados2,Time,estados2)
                hold(app.grafico_estados2,'on')
                plot(app.grafico_estados2,Time,estados_estimados2)

                plot(app.grafico_estados3,Time,estados3)
                hold(app.grafico_estados3,'on')
                plot(app.grafico_estados3,Time,estados_estimados3)

                plot(app.grafico_estados4,Time,estados4)
                hold(app.grafico_estados4,'on')
                plot(app.grafico_estados4,Time,estados_estimados4)

                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')

                legend(app.grafico_estados1,'Posição Angular Viga','Posicao Estimada')
                legend(app.grafico_estados2,'Velocidade Angular Viga','Velocidade Estimada')
                legend(app.grafico_estados3,'Posição Bola', 'Posicao Estimada')
                legend(app.grafico_estados4,'Velocidade Bola','Velocidade Estimada')
            end 


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.mbEditFieldLabel = uilabel(app.Tab);
            app.mbEditFieldLabel.Text = 'mb';
            app.mbEditField = uieditfield(app.Tab, 'numeric');
            app.mbEditField.Value = 0.064;
            app.mvEditFieldLabel = uilabel(app.Tab);
            app.mvEditFieldLabel.Text = 'mv';
            app.mvEditField = uieditfield(app.Tab, 'numeric');
            app.mvEditField.Value = 0.65;
            app.REditFieldLabel = uilabel(app.Tab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.Tab, 'numeric');
            app.REditField.Value = 0.425;
            app.LEditFieldLabel = uilabel(app.Tab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.Tab, 'numeric');
            app.LEditField.Value = 0.425;
            app.deltaEditFieldLabel = uilabel(app.Tab);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.Tab, 'numeric');
            app.deltaEditField.Value = 0.2;
            app.RunSimulationButton = uibutton(app.Tab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.dEditFieldLabel = uilabel(app.Tab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.Tab, 'numeric');
            app.dEditField.Value = 0.12;
            app.ControladorButtonGroup = uibuttongroup(app.Tab);
            app.ControladorButtonGroup.Title = 'Controlador';
            app.LQRButton = uiradiobutton(app.ControladorButtonGroup);
            app.LQRButton.Text = 'LQR';
            app.LQRButton.Value = true;
            app.alocacaoButton = uiradiobutton(app.ControladorButtonGroup);
            app.alocacaoButton.Text = 'alocacao';
            app.MPCButton = uiradiobutton(app.ControladorButtonGroup);
            app.MPCButton.Text = 'MPC';
            app.PIDButton = uiradiobutton(app.ControladorButtonGroup);
            app.PIDButton.Text = 'PID';
            app.EntradaButtonGroup = uibuttongroup(app.Tab);
            app.EntradaButtonGroup.Title = 'Entrada';
            app.DegrauButton = uiradiobutton(app.EntradaButtonGroup);
            app.DegrauButton.Text = 'Degrau';
            app.DegrauButton.Value = true;
            app.SineButton = uiradiobutton(app.EntradaButtonGroup);
            app.SineButton.Text = 'Sine';
            app.TempoEditFieldLabel = uilabel(app.Tab);
            app.TempoEditFieldLabel.Text = 'Tempo';
            app.TempoEditField = uieditfield(app.Tab, 'numeric');
            app.TempoEditField.Value = 200;
            app.AmplitudeEntradaSliderLabel = uilabel(app.Tab);
            app.AmplitudeEntradaSliderLabel.Text = 'Amplitude Entrada';
            app.AmplitudeEntradaSlider = uislider(app.Tab);
            app.AmplitudeEntradaSlider.Limits = [-3 3];
            app.StopSimulationButton = uibutton(app.Tab, 'push');
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.LinearizaroButtonGroup = uibuttongroup(app.Tab);
            app.LinearizaroButtonGroup.Title = 'Linearizarção';
            app.LinearButton = uiradiobutton(app.LinearizaroButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.NoLinearButton = uiradiobutton(app.LinearizaroButtonGroup);
            app.NoLinearButton.Text = 'Não Linear';
            app.NoLinearButton.Value = true;
            app.ObservadordeestadosButtonGroup = uibuttongroup(app.Tab);
            app.ObservadordeestadosButtonGroup.Title = 'Observador de estados';
            app.ComObservadorButton = uiradiobutton(app.ObservadordeestadosButtonGroup);
            app.ComObservadorButton.Text = 'Com Observador';
            app.SemObservadorButton = uiradiobutton(app.ObservadordeestadosButtonGroup);
            app.SemObservadorButton.Text = 'Sem Observador';
            app.SemObservadorButton.Value = true;
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.grafico_acao_controle = uiaxes(app.Tab2);
            title(app.grafico_acao_controle, 'Ação Controle no tempo')
            xlabel(app.grafico_acao_controle, 'Tempo')
            ylabel(app.grafico_acao_controle, 'Ação Controle')
            zlabel(app.grafico_acao_controle, 'Z')
            app.grafico_simulacao = uiaxes(app.Tab2);
            title(app.grafico_simulacao, 'Posição da bola no tempo')
            xlabel(app.grafico_simulacao, 'Tempo')
            ylabel(app.grafico_simulacao, 'Posição da bola')
            zlabel(app.grafico_simulacao, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.grafico_estados4 = uiaxes(app.Tab3);
            title(app.grafico_estados4, 'Velocidade da Bola no Tempo')
            xlabel(app.grafico_estados4, 'Tempo')
            ylabel(app.grafico_estados4, 'Velocidade da Bola ')
            zlabel(app.grafico_estados4, 'Z')
            app.grafico_estados3 = uiaxes(app.Tab3);
            title(app.grafico_estados3, 'Posição Bola no Tempo')
            xlabel(app.grafico_estados3, 'Tempo')
            ylabel(app.grafico_estados3, 'Posição da Bola')
            zlabel(app.grafico_estados3, 'Z')
            app.grafico_estados2 = uiaxes(app.Tab3);
            title(app.grafico_estados2, 'Velocidade Angula da Viga no Tempo')
            xlabel(app.grafico_estados2, 'Tempo')
            ylabel(app.grafico_estados2, 'Velocidade Angular da viga')
            zlabel(app.grafico_estados2, 'Z')
            app.grafico_estados1 = uiaxes(app.Tab3);
            title(app.grafico_estados1, 'Angulo da Viga no Tempo')
            xlabel(app.grafico_estados1, 'Tempo')
            ylabel(app.grafico_estados1, 'Angulo da Viga')
            zlabel(app.grafico_estados1, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end