classdef app_ball_and_beam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SistemaTab                      matlab.ui.container.Tab
        AmplitudeEntradaSlider          matlab.ui.control.Slider
        AmplitudeEntradaSliderLabel     matlab.ui.control.Label
        TempoEditField                  matlab.ui.control.NumericEditField
        TempoEditFieldLabel             matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        deltaEditField                  matlab.ui.control.NumericEditField
        deltaEditFieldLabel             matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        REditField                      matlab.ui.control.NumericEditField
        REditFieldLabel                 matlab.ui.control.Label
        mvEditField                     matlab.ui.control.NumericEditField
        mvEditFieldLabel                matlab.ui.control.Label
        mbEditField                     matlab.ui.control.NumericEditField
        mbEditFieldLabel                matlab.ui.control.Label
        ImagemButton                    matlab.ui.control.Button
        ObservadordeestadosButtonGroup  matlab.ui.container.ButtonGroup
        SemObservadorButton             matlab.ui.control.RadioButton
        ComObservadorButton             matlab.ui.control.RadioButton
        LinearizaroButtonGroup          matlab.ui.container.ButtonGroup
        NoLinearButton                  matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        StopSimulationButton            matlab.ui.control.Button
        EntradaButtonGroup              matlab.ui.container.ButtonGroup
        SineButton                      matlab.ui.control.RadioButton
        DegrauButton                    matlab.ui.control.RadioButton
        ControladoresTab                matlab.ui.container.Tab
        KDPIDEditField                  matlab.ui.control.NumericEditField
        KDPIDEditFieldLabel             matlab.ui.control.Label
        KIPIDEditField                  matlab.ui.control.NumericEditField
        KIPIDEditFieldLabel             matlab.ui.control.Label
        KPPIDEditField                  matlab.ui.control.EditField
        KPPIDEditFieldLabel             matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        kiEditField                     matlab.ui.control.NumericEditField
        kiEditFieldLabel                matlab.ui.control.Label
        AutomaticoButtonGroup           matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutomaticoButton                matlab.ui.control.RadioButton
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        ControladorButtonGroup          matlab.ui.container.ButtonGroup
        PIDButton                       matlab.ui.control.RadioButton
        MPCButton                       matlab.ui.control.RadioButton
        alocacaoButton                  matlab.ui.control.RadioButton
        LQRButton                       matlab.ui.control.RadioButton
        SaidasTab                       matlab.ui.container.Tab
        grafico_simulacao               matlab.ui.control.UIAxes
        grafico_acao_controle           matlab.ui.control.UIAxes
        EstadosTab                      matlab.ui.container.Tab
        grafico_estados1                matlab.ui.control.UIAxes
        grafico_estados2                matlab.ui.control.UIAxes
        grafico_estados3                matlab.ui.control.UIAxes
        grafico_estados4                matlab.ui.control.UIAxes
        MetricasTab                     matlab.ui.container.Tab
        MaximaAodecontroleEditField     matlab.ui.control.NumericEditField
        MaximaAodecontroleEditFieldLabel  matlab.ui.control.Label
        TempodesubidaEditField          matlab.ui.control.NumericEditField
        TempodesubidaEditFieldLabel     matlab.ui.control.Label
        TempodeAcomodaoEditField        matlab.ui.control.NumericEditField
        TempodeAcomodaoEditFieldLabel   matlab.ui.control.Label
        MaximoSobressinalEditField      matlab.ui.control.NumericEditField
        MaximoSobressinalEditFieldLabel  matlab.ui.control.Label
        TempodepicoEditField            matlab.ui.control.NumericEditField
        TempodepicoEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        % sistem property
        g = 9.8;
        mb = 0;
        mv = 0;
        L = 0; 
        d = 0;
        R  = 0;
        delta = 0;
       
        % model property
        Jv =0;
        A = 0;
        B = 0;
        C = 0;
        D = 0;
        a23 = 0;
        a41= 0;
        a = 0;
        b = 0;
        F0 = 0;
        
        % simulation prperty
        tempo_simulacao = 0;
        amplitude_entrada = 0;
        bool_degrau = 0;
        bool_linear = 0;

        % mpc property 
        mpc1 = 0;
        mpc_no_linear = 0;
        
        % steady state matriz
        A_null = 0; 
        B_null = 0;
        C_null = 0;
        D_null = 0;
        r_null = 0;
        
        % controladores
        k_seguimento = 0 ;
        ki_seguimento = 0;
        
        %observadores 
        L_observer = 0;

        % metricas 
        tempo_de_pico = 0;
        maximo_sobressinal = 0;
        tempo_de_acomodacao = 0;
        tempo_de_subida = 0;
        maxima_acao_de_controle = 0;
        
    end
    
    methods (Access = private)
        
        function clean_grafics(app)
            cla(app.grafico_simulacao)
            cla(app.grafico_acao_controle)
            cla(app.grafico_estados1)
            cla(app.grafico_estados2)
            cla(app.grafico_estados3)
            cla(app.grafico_estados4)
        end

        function set_values(app)
            app.mb = app.mbEditField.Value;
            app.mv = app.mvEditField.Value;
            app.L  = app.LEditField.Value;
            app.d = app.dEditField.Value;
            app.R  = app.REditField.Value;
            app.delta = app.deltaEditField.Value;
            app.tempo_simulacao = app.TempoEditField.Value;
            app.amplitude_entrada = app.AmplitudeEntradaSlider.Value;
            app.bool_degrau = app.DegrauButton.Value;
            app.bool_linear = app.LinearButton.Value;
             
            app.mpc1 = load('mpc_session_designer_ball_and_beam_linear.mat');
            app.mpc1 = app.mpc1.MPCDesignerSession.AppData.Controllers.MPC;

            app.mpc_no_linear = load('mpc_session_designer_ball_and_beam_linear.mat');
            app.mpc_no_linear = app.mpc_no_linear.MPCDesignerSession.AppData.Controllers.MPC ;


        end 
        function create_system_model(app)

            app.Jv =  (1/2) * app.mv * app.L^2;
            app.a23 = -(( app.mb * app.Jv *app.g + (app.mb^2 )*app.g * app.delta^2 )/((app.Jv + app.mb*app.delta^2)^2));
            app.a41 = -5*app.g/7;

            app.A = [ 0 1 0  0; 0 0 app.a23 0; 
                      0 0 0 1; app.a41 0 0 0 ];
            app.B = [0; 1; 0; 0];  
            app.C = [0 0 1 0];
            app.D = 0;
            
            [app.a, app.b] = ss2tf(app.A, app.B, app.C, app.D);

            app.F0 = app.a(5)/app.b(5); % funcao de transferencia com S avaliado em zero

            % SS error null 
            app.A_null = [app.A; app.C];
            coluna_nula = zeros(5,1);
            app.A_null = [app.A_null coluna_nula];
            app.B_null = [app.B; 0];
            app.C_null = [app.C 1];
            app.r_null = [0;0;0;0;-1];
            
        end 

        function set_controller_values(app)

            k_teste = app.kEditField.Value;
            app.k_seguimento = str2double(strsplit(k_teste,','));

            app.ki_seguimento = app.kiEditField.Value;
        end

        function lqr_controller(app)
            Q_null = eye(5);
            R_lqr = 0.5;

            % equação de riccati
            P_null = icare(app.A_null,app.B_null,Q_null,R_lqr);
            K_null = (R_lqr^-1)*transpose(app.B_null)*P_null;

            app.k_seguimento = K_null(1:4);
            app.ki_seguimento = K_null(5);

        end 

        function pole_alocation_controller(app)
            pb = [-0.3 -2.5 -5 -10 -11];
            kb_seguimento_alocacao = acker(app.A_null, app.B_null, pb);
        
            app.k_seguimento  = kb_seguimento_alocacao(1:4);
            app.ki_seguimento = kb_seguimento_alocacao(5);
        end 

        function state_observer(app)
            syms  s;
            fast_pole = min(real(eig(app.A)));
            pole_f = 1.5 * fast_pole;
            system_f = (s - pole_f)^length(app.A);
            dem = sym2poly(system_f);
            tf_system = tf(1,dem);
            [num, dem] = tfdata(tf_system, 'v');
            [F,~,~,~] = tf2ss(num,dem);
            Lchp = [0; 0; 0; 1];
       
            T = lyap(F, -app.A,Lchp*app.C);
            app.L_observer = inv(T)*Lchp;

        end 
        
        function model = load_model(app)

            if (app.NoLinearButton.Value == 1) && (app.SemObservadorButton.Value == 1) &&(app.MPCButton.Value == 0) 
            model = 'model_no_linear1';
            end 

            if (app.LinearButton.Value) && (app.SemObservadorButton.Value == 1) && (app.MPCButton.Value == 0) 
            model = 'model_linear';
            end 

            if (app.LinearButton.Value) && (app.ComObservadorButton.Value == 1) && (app.MPCButton.Value == 0) 
            model = 'model_linear_observador';
            end 

            if (app.NoLinearButton.Value == 1) && (app.ComObservadorButton.Value == 1) &&(app.MPCButton.Value == 0) 
            model = 'model_no_linear_observador';
            end 

            if (app.LinearButton.Value) && (app.SemObservadorButton.Value == 1) && (app.MPCButton.Value == 1) 
            model = 'model_linear_mpc';
            end 

            if (app.NoLinearButton.Value) && (app.SemObservadorButton.Value == 1) && (app.MPCButton.Value == 1) 
            model = 'model_no_linear_mpc';
            end 

            if (app.LinearButton.Value) && (app.SemObservadorButton.Value == 1) && (app.PIDButton.Value == 1) 
            model = 'model_linear_pid';
            end 
        end 

        function model_output = set_model_variables(app, model)
            
            load_system(model)
            set_param(model,'StartTime','0','StopTime', num2str(app.tempo_simulacao))
            in = Simulink.SimulationInput(model);
            in = in.setVariable('g',app.g);
            in = in.setVariable('mb',app.mb);
            in = in.setVariable('L',app.L);
            in = in.setVariable('mv',app.mv);
            in = in.setVariable('d',app.d);
            in = in.setVariable('R',app.R);
            in = in.setVariable('delta',app.delta);
            in = in.setVariable('Jv',app.Jv);
            in = in.setVariable('A',app.A);
            in = in.setVariable('B',app.B);
            in = in.setVariable('C',app.C);
            in = in.setVariable('D',app.D);
            in = in.setVariable('k_seguimento',app.k_seguimento);
            in = in.setVariable('ki_seguimento',app.ki_seguimento);
            in = in.setVariable('amplitude_entrada', app.amplitude_entrada);
            in = in.setVariable('L_observer', app.L_observer);
            in = in.setVariable('bool_degrau', app.bool_degrau);
            in = in.setVariable('bool_linear',app.bool_linear);
            in = in.setVariable('mpc1', app.mpc1);
            in = in.setVariable('mpc_no_linear', app.mpc_no_linear);
            model_output = sim(in);

        end 

        function calculate_performance_metrics(app,model_output)
            
       
            Time = model_output.u_output.Time; 
            y_out  = model_output.y_output.Data;
            u_out = model_output.u_output.Data;
            acao_controle = model_output.acao_controle.Data;
            Time_acao_controle = model_output.acao_controle.Time;

            app.maximo_sobressinal = max(y_out)/max(u_out);
            app.maxima_acao_de_controle = max(acao_controle);
            maxima_entrada = max(u_out);

            index = find(y_out == max(y_out));
            index = index(1);
            app.tempo_de_pico = Time(index);

            r = 1; 

            while (y_out(r) < maxima_entrada*0.9)
                if r < length(y_out)
                    r = r + 1;
                else
                    break
                end
            end

            app.tempo_de_subida= Time(r);
            
            s = length(Time);

            while y_out(s) > maxima_entrada*0.98 && y_out(s) < maxima_entrada*1.02
                s = s - 1; 
            end

            app.tempo_de_acomodacao = Time(s)
       

        end

        function set_metrics(app)
         
            app.TempodepicoEditField.Value = app.tempo_de_pico;
            app.MaximoSobressinalEditField.Value = app.maximo_sobressinal;
            app.MaximaAodecontroleEditField.Value = app.maxima_acao_de_controle;
            app.TempodeAcomodaoEditField.Value = app.tempo_de_acomodacao;
            app.TempodesubidaEditField.Value = app.tempo_de_subida;
         
        end 

        function plot_animate_grafics_deprecreated(app,axes, time, y)

            h = plot(axes,nan,nan);
            hold(axes,'on')
            grid(axes,'on')
            xlim(axes,[min(time) max(time)])
            min_y =  min(y) - 0.1;
            max_y =  max(y) + 0.1;

            ylim(axes, [min_y max_y])

            for k = 1:numel(time)
                set(h,'Xdata',time(1:k), 'YData',y(1:k))
                drawnow; pause(0.01)
            end 
        end 

         function plot_animate_grafics(app,axes, time, y)
            h = animatedline(axes, 'color','b');
            min_y =  min(y) - 0.1;
            max_y =  max(y) + 0.1;
            xlim(axes,[min(time) max(time)])
            ylim(axes, [min_y max_y])

            loops = numel(time);
            for k = 1:loops
                addpoints(h, time(k),y(k))
                drawnow
            end 
         end 

         function plot_animate_grafics_together(app,axes, time, y, y2)
            h = animatedline(axes, 'color','b');
            h2 = animatedline(axes, 'color','r');
  
            min_y =  min(y) - 0.1;
            max_y =  max(y) + 0.1;
            xlim(axes,[min(time) max(time)])
            ylim(axes, [min_y max_y])

            loops = numel(time);
            for k = 1:loops
                addpoints(h, time(k),y(k))
                addpoints(h2, time(k),y2(k))
                drawnow
            end 
        end 

        function plot_grafics2(app, model_output)
            Time = model_output.u_output.Time; 
            y_out  = model_output.y_output.Data;
            u_out = model_output.u_output.Data;
            acao_controle = model_output.acao_controle.Data;
            Time_acao_controle = model_output.acao_controle.Time;

            estados1 = model_output.output_estados_1.Data;
            estados2 = model_output.output_estados_2.Data;
            estados3 = model_output.output_estados_3.Data;
            estados4 = model_output.output_estados_4.Data;
            
            app.plot_animate_grafics_together(app.grafico_simulacao, Time, y_out, u_out)
            app.plot_animate_grafics(app.grafico_acao_controle, Time_acao_controle, acao_controle)
            if app.SemObservadorButton.Value

                app.plot_animate_grafics(app.grafico_estados1,Time,estados1)
                app.plot_animate_grafics(app.grafico_estados2,Time,estados2)
                app.plot_animate_grafics(app.grafico_estados3,Time,estados3)
                app.plot_animate_grafics(app.grafico_estados4,Time,estados4)

                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')
                legend(app.grafico_estados1,'Posição Angular Viga')
                legend(app.grafico_estados2,'Velocidade Angular Viga')
                legend(app.grafico_estados3,'Posição Bola')
                legend(app.grafico_estados4,'Velocidade Bola')
            end

            if app.ComObservadorButton.Value

                estados_estimados1 = model_output.output_estados_observador_1.Data;
                estados_estimados2 = model_output.output_estados_observador_2.Data;
                estados_estimados3 = model_output.output_estados_observador_3.Data;
                estados_estimados4 = model_output.output_estados_observador_4.Data;

                app.plot_animate_grafics_together(app.grafico_estados1,Time,estados1,estados_estimados1)
                app.plot_animate_grafics_together(app.grafico_estados2,Time,estados2,estados_estimados2)
                app.plot_animate_grafics_together(app.grafico_estados3,Time,estados3,estados_estimados3)
                app.plot_animate_grafics_together(app.grafico_estados4,Time,estados4,estados_estimados4)

                legend(app.grafico_estados1,'Posição Angular Viga','Posicao Estimada')
                legend(app.grafico_estados2,'Velocidade Angular Viga','Velocidade Estimada')
                legend(app.grafico_estados3,'Posição Bola', 'Posicao Estimada')
                legend(app.grafico_estados4,'Velocidade Bola','Velocidade Estimada')
                
            end
          

        end 
        function plot_grafics(app, model_output)
   
            Time = model_output.u_output.Time; 
            y_out  = model_output.y_output.Data;
            u_out = model_output.u_output.Data;
            acao_controle = model_output.acao_controle.Data;
            Time_acao_controle = model_output.acao_controle.Time;

            estados1 = model_output.output_estados_1.Data;
            estados2 = model_output.output_estados_2.Data;
            estados3 = model_output.output_estados_3.Data;
            estados4 = model_output.output_estados_4.Data;
            
            plot(app.grafico_simulacao, Time, y_out)
            hold(app.grafico_simulacao,'on');
            plot(app.grafico_simulacao,Time, u_out) 
            plot(app.grafico_acao_controle, Time_acao_controle, acao_controle)

            plot(app.grafico_estados1,Time,estados1)
            plot(app.grafico_estados2,Time,estados2)
            plot(app.grafico_estados3,Time,estados3)
            plot(app.grafico_estados4,Time,estados4)

            if app.SemObservadorButton.Value
                legend(app.grafico_simulacao,'Posição', 'Setpoint')
                legend(app.grafico_acao_controle,'Ação Controle')
                legend(app.grafico_estados1,'Posição Angular Viga')
                legend(app.grafico_estados2,'Velocidade Angular Viga')
                legend(app.grafico_estados3,'Posição Bola')
                legend(app.grafico_estados4,'Velocidade Bola')
            end 

            if app.ComObservadorButton.Value
                estados_estimados1 = model_output.output_estados_observador_1.Data;
                estados_estimados2 = model_output.output_estados_observador_2.Data;
                estados_estimados3 = model_output.output_estados_observador_3.Data;
                estados_estimados4 = model_output.output_estados_observador_4.Data;
                
                hold(app.grafico_estados1,'on')
                plot(app.grafico_estados1, Time, estados_estimados1)
                hold(app.grafico_estados2,'on')
                plot(app.grafico_estados2, Time, estados_estimados2)
                hold(app.grafico_estados3,'on')
                plot(app.grafico_estados3, Time, estados_estimados3)
                hold(app.grafico_estados4,'on')
                plot(app.grafico_estados4, Time, estados_estimados4)


                legend(app.grafico_estados1,'Posição Angular Viga','Posicao Estimada')
                legend(app.grafico_estados2,'Velocidade Angular Viga','Velocidade Estimada')
                legend(app.grafico_estados3,'Posição Bola', 'Posicao Estimada')
                legend(app.grafico_estados4,'Velocidade Bola','Velocidade Estimada')

            end 


        end 

   
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
             
            app.clean_grafics()
            app.set_values()
            app.create_system_model()

            if app.AutomaticoButton.Value == true
                if app.LQRButton.Value
                    app.lqr_controller();
                end
    
                if app.alocacaoButton.Value
                   app.pole_alocation_controller();
                end 
    
                if app.ComObservadorButton.Value 
                   app.state_observer();
                end 
            end

            if app.ManualButton.Value
               
                app.set_controller_values();

            end 

            model = load_model(app);
            model_output = app.set_model_variables(model);
            
            app.plot_grafics(model_output);
            app.calculate_performance_metrics(model_output)
            app.set_metrics()


        end

        % Button pushed function: ImagemButton
        function ImagemButtonPushed(app, event)
            % Basic dimensions.
            
            ballradius = 1;
            beamlength = 20;
            beamwidth = 0.3;
            
            % System parameters
            
            xmin = 0;                   % Left limit
            xmax = beamlength;          % Right limit
            umin = -0.25;               % Minimum beam angle
            umax = 0.25;                % Maximum beam angle
            g = 0.05;                   % Gravitational Constant
            dt = 0.2;                   % Time Step
            
            % Set initial conditions for ball and beam position
            
            t = 0;                      % Initial time
            x = beamlength/2;           % Initial Ball position
            v = 0;                      % Initial Velocity
            u = 0;                      % Initial beam angle
            done = 0;                   % Done Flag
            
            % Setup controller with initial parameters
            
            mode = 1;                   % Flag, start in manual mode
                                        %   Mode == 1 => Manual
                                        %   Mode == 2 => Proportional
                                        %   Mode == 3 => PD
            Kp = 0.1;                   % Proportional Gain
            Td = 20;                    % Derivative time constant
            N = 10;                     % Maximum derivative gain
            xsp = x;                    % Initial setpoint
            xlast = x;                  % Last measured value
            dterm = 0;                  % Derivative term
            Ad = Td/(Td+N*dt);          % Derivative filter constant
            Bd = Kp*Td*N/(Td+N*dt);     % Derivative gain
            
            % Initialize Data stores
            
            xdata = x;
            tdata = t;
            udata = u;
            xspdata = xsp;
            
            % Create figure
            
            figure( ...
                'Menubar','None',...
                'Name','Ball & Beam Demo', ...
                'NumberTitle','off', ...
                'BackingStore','off',...
                'CloseRequestFcn','ballbeam(''close'')');
            
            % Create axes to hold ball & beam animation and plot
            
            bbAxes = axes(...
                'Units','normalized', ...
                'Position',[0.02 0.02 0.75 0.70],...
                'Visible','off',...
                'NextPlot','add');
            
            plotAxes = axes(...
                'Units','normalized',...
                'Position',[0.08 0.65 0.70 0.30],...
                'Visible','off');
            
            % Information for all buttons
            xPos=0.85;
            btnWid=0.12;
            btnHt=0.08;
            % Spacing between the button and the next command's label
            spacing=0.025;
            
            
            %====================================
            % CONSOLE frame container for buttons
            frmBorder=0.02;
            yPos=0.05-frmBorder;
            frmPos=[xPos-frmBorder yPos btnWid+2*frmBorder 0.9+2*frmBorder];
            uicontrol( ...
                'Style','frame', ...
                'Units','normalized', ...
                'Position',frmPos, ...
                'BackgroundColor',[0.5 0.5 0.7]);
            
            
            % Create Ball, keep handle
            a = 2*pi*(0:.05:.95)';
            xball = ballradius*sin(a);
            yball = ballradius*(1+cos(a));
            ballHndl = patch(xball+x,yball,1);
            
            % Create Beam
            xbeam = beamlength*[0 1 1 0]';
            ybeam = beamwidth*[0 0 -1 -1]';
            beamHndl = patch(xbeam,ybeam,2);
        end

        % Selection changed function: AutomaticoButtonGroup
        function AutomaticoButtonGroupSelectionChanged(app, event)
            selectedButton = app.AutomaticoButtonGroup.SelectedObject;
            
            if app.AutomaticoButton.Value == true
                app.kEditField.Enable= "off";
                app.kiEditField.Enable = "off";
                app.KPPIDEditField.Enable = "off";
                app.KIPIDEditField.Enable = "off";
                app.KDPIDEditField.Enable = "off";

            end 
        
            if app.ManualButton.Value == true
                if (app.alocacaoButton.Value == true) || (app.LQRButton.Value == true)
                    app.kEditField.Enable = "on";
                    app.kiEditField.Enable = "on";

                    app.KPPIDEditField.Enable = "off";
                    app.KIPIDEditField.Enable = "off";
                    app.KDPIDEditField.Enable = "off";

                end

                if app.PIDButton.Value == true
                    app.KPPIDEditField.Enable = "on";
                    app.KIPIDEditField.Enable = "on";
                    app.KDPIDEditField.Enable = "on";

                    app.kEditField.Enable = "off";
                    app.kiEditField.Enable = "off";
                end

            end


        end

        % Selection changed function: ControladorButtonGroup
        function ControladorButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControladorButtonGroup.SelectedObject;
            if app.ManualButton.Value == true
               if (app.alocacaoButton.Value == true) || (app.LQRButton.Value == true)
                    app.kEditField.Enable = "on";
                    app.kiEditField.Enable = "on";

                   app.KPPIDEditField.Enable = "off";
                   app.KIPIDEditField.Enable = "off";
                   app.KDPIDEditField.Enable = "off";
               end
               if app.PIDButton.Value == true
                   app.KPPIDEditField.Enable = "on";
                   app.KIPIDEditField.Enable = "on";
                   app.KDPIDEditField.Enable = "on";


                   app.kEditField.Enable = "off";
                   app.kiEditField.Enable = "off";
               end
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SistemaTab = uitab(app.TabGroup);
            app.SistemaTab.Title = 'Sistema';
            app.EntradaButtonGroup = uibuttongroup(app.SistemaTab);
            app.EntradaButtonGroup.Title = 'Entrada';
            app.DegrauButton = uiradiobutton(app.EntradaButtonGroup);
            app.DegrauButton.Text = 'Degrau';
            app.DegrauButton.Value = true;
            app.SineButton = uiradiobutton(app.EntradaButtonGroup);
            app.SineButton.Text = 'Sine';
            app.StopSimulationButton = uibutton(app.SistemaTab, 'push');
            app.StopSimulationButton.Text = 'Stop Simulation';
            app.LinearizaroButtonGroup = uibuttongroup(app.SistemaTab);
            app.LinearizaroButtonGroup.Title = 'Linearizarção';
            app.LinearButton = uiradiobutton(app.LinearizaroButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.NoLinearButton = uiradiobutton(app.LinearizaroButtonGroup);
            app.NoLinearButton.Text = 'Não Linear';
            app.NoLinearButton.Value = true;
            app.ObservadordeestadosButtonGroup = uibuttongroup(app.SistemaTab);
            app.ObservadordeestadosButtonGroup.Title = 'Observador de estados';
            app.ComObservadorButton = uiradiobutton(app.ObservadordeestadosButtonGroup);
            app.ComObservadorButton.Text = 'Com Observador';
            app.SemObservadorButton = uiradiobutton(app.ObservadordeestadosButtonGroup);
            app.SemObservadorButton.Text = 'Sem Observador';
            app.SemObservadorButton.Value = true;
            app.ImagemButton = uibutton(app.SistemaTab, 'push');
            app.ImagemButton.ButtonPushedFcn = createCallbackFcn(app, @ImagemButtonPushed, true);
            app.ImagemButton.Text = 'Imagem';
            app.mbEditFieldLabel = uilabel(app.SistemaTab);
            app.mbEditFieldLabel.Text = 'mb';
            app.mbEditField = uieditfield(app.SistemaTab, 'numeric');
            app.mbEditField.Value = 0.064;
            app.mvEditFieldLabel = uilabel(app.SistemaTab);
            app.mvEditFieldLabel.Text = 'mv';
            app.mvEditField = uieditfield(app.SistemaTab, 'numeric');
            app.mvEditField.Value = 0.65;
            app.REditFieldLabel = uilabel(app.SistemaTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.SistemaTab, 'numeric');
            app.REditField.Value = 0.425;
            app.LEditFieldLabel = uilabel(app.SistemaTab);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.SistemaTab, 'numeric');
            app.LEditField.Value = 0.425;
            app.deltaEditFieldLabel = uilabel(app.SistemaTab);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.SistemaTab, 'numeric');
            app.deltaEditField.Value = 0.2;
            app.dEditFieldLabel = uilabel(app.SistemaTab);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.SistemaTab, 'numeric');
            app.dEditField.Value = 0.12;
            app.TempoEditFieldLabel = uilabel(app.SistemaTab);
            app.TempoEditFieldLabel.Text = 'Tempo';
            app.TempoEditField = uieditfield(app.SistemaTab, 'numeric');
            app.TempoEditField.Value = 100;
            app.AmplitudeEntradaSliderLabel = uilabel(app.SistemaTab);
            app.AmplitudeEntradaSliderLabel.Text = 'Amplitude Entrada';
            app.AmplitudeEntradaSlider = uislider(app.SistemaTab);
            app.AmplitudeEntradaSlider.Limits = [-3 3];
            app.AmplitudeEntradaSlider.Value = 1;
            app.ControladoresTab = uitab(app.TabGroup);
            app.ControladoresTab.Title = 'Controladores';
            app.ControladorButtonGroup = uibuttongroup(app.ControladoresTab);
            app.ControladorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ControladorButtonGroupSelectionChanged, true);
            app.ControladorButtonGroup.Title = 'Controlador';
            app.LQRButton = uiradiobutton(app.ControladorButtonGroup);
            app.LQRButton.Text = 'LQR';
            app.LQRButton.Value = true;
            app.alocacaoButton = uiradiobutton(app.ControladorButtonGroup);
            app.alocacaoButton.Text = 'alocacao';
            app.MPCButton = uiradiobutton(app.ControladorButtonGroup);
            app.MPCButton.Text = 'MPC';
            app.PIDButton = uiradiobutton(app.ControladorButtonGroup);
            app.PIDButton.Text = 'PID';
            app.kEditFieldLabel = uilabel(app.ControladoresTab);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.ControladoresTab, 'text');
            app.kEditField.Enable = 'off';
            app.kEditField.Value = '23.6049,7.0150, -21.7075 , -8.7476';
            app.AutomaticoButtonGroup = uibuttongroup(app.ControladoresTab);
            app.AutomaticoButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AutomaticoButtonGroupSelectionChanged, true);
            app.AutomaticoButtonGroup.Title = 'Automatico';
            app.AutomaticoButton = uiradiobutton(app.AutomaticoButtonGroup);
            app.AutomaticoButton.Text = 'Automatico';
            app.AutomaticoButton.Value = true;
            app.ManualButton = uiradiobutton(app.AutomaticoButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.kiEditFieldLabel = uilabel(app.ControladoresTab);
            app.kiEditFieldLabel.Text = 'ki';
            app.kiEditField = uieditfield(app.ControladoresTab, 'numeric');
            app.kiEditField.Enable = 'off';
            app.kiEditField.Value = -1.4142;
            app.RunSimulationButton = uibutton(app.ControladoresTab, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.KPPIDEditFieldLabel = uilabel(app.ControladoresTab);
            app.KPPIDEditFieldLabel.Text = 'KP PID';
            app.KPPIDEditField = uieditfield(app.ControladoresTab, 'text');
            app.KPPIDEditField.Enable = 'off';
            app.KIPIDEditFieldLabel = uilabel(app.ControladoresTab);
            app.KIPIDEditFieldLabel.Text = 'KI PID';
            app.KIPIDEditField = uieditfield(app.ControladoresTab, 'numeric');
            app.KIPIDEditField.Enable = 'off';
            app.KDPIDEditFieldLabel = uilabel(app.ControladoresTab);
            app.KDPIDEditFieldLabel.Text = 'KD PID';
            app.KDPIDEditField = uieditfield(app.ControladoresTab, 'numeric');
            app.KDPIDEditField.Enable = 'off';
            app.SaidasTab = uitab(app.TabGroup);
            app.SaidasTab.Title = 'Saidas';
            app.grafico_acao_controle = uiaxes(app.SaidasTab);
            title(app.grafico_acao_controle, 'Ação Controle no tempo')
            xlabel(app.grafico_acao_controle, 'Tempo')
            ylabel(app.grafico_acao_controle, 'Ação Controle')
            zlabel(app.grafico_acao_controle, 'Z')
            app.grafico_simulacao = uiaxes(app.SaidasTab);
            title(app.grafico_simulacao, 'Posição da bola no tempo')
            xlabel(app.grafico_simulacao, 'Tempo')
            ylabel(app.grafico_simulacao, 'Posição da bola')
            zlabel(app.grafico_simulacao, 'Z')
            app.EstadosTab = uitab(app.TabGroup);
            app.EstadosTab.Title = 'Estados';
            app.grafico_estados4 = uiaxes(app.EstadosTab);
            title(app.grafico_estados4, 'Velocidade da Bola no Tempo')
            xlabel(app.grafico_estados4, 'Tempo')
            ylabel(app.grafico_estados4, 'Velocidade da Bola ')
            zlabel(app.grafico_estados4, 'Z')
            app.grafico_estados3 = uiaxes(app.EstadosTab);
            title(app.grafico_estados3, 'Posição Bola no Tempo')
            xlabel(app.grafico_estados3, 'Tempo')
            ylabel(app.grafico_estados3, 'Posição da Bola')
            zlabel(app.grafico_estados3, 'Z')
            app.grafico_estados2 = uiaxes(app.EstadosTab);
            title(app.grafico_estados2, 'Velocidade Angula da Viga no Tempo')
            xlabel(app.grafico_estados2, 'Tempo')
            ylabel(app.grafico_estados2, 'Velocidade Angular da viga')
            zlabel(app.grafico_estados2, 'Z')
            app.grafico_estados1 = uiaxes(app.EstadosTab);
            title(app.grafico_estados1, 'Angulo da Viga no Tempo')
            xlabel(app.grafico_estados1, 'Tempo')
            ylabel(app.grafico_estados1, 'Angulo da Viga')
            zlabel(app.grafico_estados1, 'Z')
            app.MetricasTab = uitab(app.TabGroup);
            app.MetricasTab.Title = 'Metricas';
            app.TempodepicoEditFieldLabel = uilabel(app.MetricasTab);
            app.TempodepicoEditFieldLabel.Text = 'Tempo de pico';
            app.TempodepicoEditField = uieditfield(app.MetricasTab, 'numeric');
            app.MaximoSobressinalEditFieldLabel = uilabel(app.MetricasTab);
            app.MaximoSobressinalEditFieldLabel.Text = 'Maximo Sobressinal';
            app.MaximoSobressinalEditField = uieditfield(app.MetricasTab, 'numeric');
            app.TempodeAcomodaoEditFieldLabel = uilabel(app.MetricasTab);
            app.TempodeAcomodaoEditFieldLabel.Text = 'Tempo de Acomodação';
            app.TempodeAcomodaoEditField = uieditfield(app.MetricasTab, 'numeric');
            app.TempodesubidaEditFieldLabel = uilabel(app.MetricasTab);
            app.TempodesubidaEditFieldLabel.Text = 'Tempo de subida';
            app.TempodesubidaEditField = uieditfield(app.MetricasTab, 'numeric');
            app.MaximaAodecontroleEditFieldLabel = uilabel(app.MetricasTab);
            app.MaximaAodecontroleEditFieldLabel.Text = 'Maxima Ação de controle';
            app.MaximaAodecontroleEditField = uieditfield(app.MetricasTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_ball_and_beam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end